classdef PC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        JeuxTab                       matlab.ui.container.Tab
        GridLayout                    matlab.ui.container.GridLayout
        GridLayout6                   matlab.ui.container.GridLayout
        UIAxes_4                      matlab.ui.control.UIAxes
        UIAxes_3                      matlab.ui.control.UIAxes
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes_1                      matlab.ui.control.UIAxes
        SelectionduchevalButtonGroup  matlab.ui.container.ButtonGroup
        GridLayout3                   matlab.ui.container.GridLayout
        GridLayout7                   matlab.ui.container.GridLayout
        HuuuHuuuuAvanceButton         matlab.ui.control.Button
        GridLayout2                   matlab.ui.container.GridLayout
        RecapLogLabel                 matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        Wheel                         matlab.ui.control.UIAxes
        ConfigurationTab              matlab.ui.container.Tab
        GridLayout5                   matlab.ui.container.GridLayout
        ListedesChevauxButtonGroup    matlab.ui.container.ButtonGroup
        GridLayout4                   matlab.ui.container.GridLayout
        SupprimerButton               matlab.ui.control.Button
        ModifierButton                matlab.ui.control.Button
        CreerButton                   matlab.ui.control.Button
        NomduChevalEditField          matlab.ui.control.EditField
        NomduChevalEditFieldLabel     matlab.ui.control.Label
        CouleurduChevalColorPicker    matlab.ui.control.ColorPicker
        CouleurduChevalColorPickerLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        Step string = "0 1 2 1 3 1 2 1 1 2 1 3 1 2 1"; % Description
        delim = " ";
        h = [];
        c = [1 0.25 0.25]; % [1 1 1]
        SpinnerLocation = 0;
        myMarkerSize = 14;
        MyListPadding = 24;
        HorsePicture = matlab.graphics.primitive.Image.empty;
        binaryImage = ones(958,826,3,'uint8');
        marker = ones(958,826,3,'uint8');
        alpha = ones(958,826,1,'logical');
        HorseStruct;

    end

    methods (Access = private)

        function results  = CheckHorseExist(app,Name,index)
            results = false;


            for idx=1:numel(app.ListedesChevauxButtonGroup.Buttons)
                if idx ~= index
                    if strcmp(app.ListedesChevauxButtonGroup.Buttons(idx).Text,strcat(" " + Name))
                        results = true;
                    end
                end

            end

        end

        function index = HorseCreationSelected(app)
            for idx=1:numel(app.ListedesChevauxButtonGroup.Buttons)
                if app.ListedesChevauxButtonGroup.Buttons(idx).Value
                    index = idx;
                    break
                end
            end


        end

        function results = CheckHorseColorExist(app,index)

            results = false;


            for idx=2:numel(app.ListedesChevauxButtonGroup.Buttons)
                if app.ListedesChevauxButtonGroup.Buttons(idx).FontColor == app.CouleurduChevalColorPicker.Value
                    if idx ~= index
                        results = true;
                    end
                end


            end


        end

        function results = k2xData(app,k)
            if k <= 10

            elseif k<=21
                k = k-11;
            elseif k<=32
                k = k-22;
            elseif k<=43
                k = k-33;
            end

            results = [k*200-75 (k*200+125)];
        end

        function results = k2Parent(app,k)
            if k <= 10
                results = app.UIAxes_1;
            elseif k<=21
                results = app.UIAxes_2;
            elseif k<=32
                results = app.UIAxes_3;
            elseif k<=43
                results = app.UIAxes_4;
            end

        end

        function k = xData2k(app,xData,Parent)

            if Parent == app.UIAxes_1
                k=(xData(1)+75)/200;
            elseif Parent == app.UIAxes_2
                k=(xData(1)+75)/200 + 11;
            elseif Parent == app.UIAxes_3
                k=(xData(1)+75)/200 + 22;
            elseif Parent == app.UIAxes_4
                k=(xData(1)+75)/200 + 33;
            end

        end

        function results = RunHorseRun(app,Step)

            index = HorseRunSelected(app);
            Previousk = app.HorseStruct(index).k;
            for k = Previousk:Step+Previousk
                app.HorsePicture(index).XData = k2xData(app,k);
                if k==11
                    app.HorsePicture(index).Parent = app.UIAxes_2;
                elseif k==22
                    app.HorsePicture(index).Parent = app.UIAxes_3;
                elseif k==33
                    app.HorsePicture(index).Parent = app.UIAxes_4;
                end
                drawnow
                pause(0.2)
            end
            app.HorseStruct(index).k = k;
            results = true;
        end

        function index = HorseRunSelected(app)
            for idx=1:numel(app.SelectionduchevalButtonGroup.Buttons)
                if app.SelectionduchevalButtonGroup.Buttons(idx).Value
                    index = idx;
                    break
                end
            end


        end


        function results = reloadGame(app)

            for idx = 1:numel(app.HorseStruct)

                nbButtons = idx;
                rb = uiradiobutton(app.ListedesChevauxButtonGroup);
                rb.Text = app.HorseStruct(nbButtons).Name;
                rb.FontSize = 22;
                rb.Position = [10 (app.ListedesChevauxButtonGroup.InnerPosition(4)-10-app.MyListPadding*nbButtons) 358 22]; % [x y width height]
                rb.FontColor = app.HorseStruct(nbButtons).Color;    % [redScalar greenScalar blueScalar]/  256  ;

                sb = uiradiobutton(app.SelectionduchevalButtonGroup);
                sb.Text = app.HorseStruct(nbButtons).Name;
                sb.FontSize = 22;
                sb.Position = [10 (app.SelectionduchevalButtonGroup.InnerPosition(4)-10-app.MyListPadding*nbButtons) 358 22]; % [x y width height]
                sb.FontColor = app.HorseStruct(nbButtons).Color;    % [redScalar greenScalar blueScalar]/  256  ;

                k=app.HorseStruct(nbButtons).k;
                Parent = k2Parent(app,k);
                red = app.marker(:,:,1);
                green = app.marker(:,:,2);
                blue = app.marker(:,:,3);
                red(app.binaryImage) = app.HorseStruct(nbButtons).Color(1)*255;
                green(app.binaryImage) = app.HorseStruct(nbButtons).Color(2)*255;
                blue(app.binaryImage) = app.HorseStruct(nbButtons).Color(3)*255;
                marker_modif = cat(3,red,green,blue);
                app.HorsePicture(nbButtons) = imshow(marker_modif,'xdata',k2xData(app,k),'ydata',[-260 -60],'Parent',Parent);
                app.HorsePicture(nbButtons).AlphaData = app.alpha;
                %xData2k(app,app.HorsePicture(nbButtons).XData,app.HorsePicture(nbButtons).Parent)


                app.HorseStruct(nbButtons).Name = app.SelectionduchevalButtonGroup.Buttons(nbButtons).Text;
                app.HorseStruct(nbButtons).Color = app.SelectionduchevalButtonGroup.Buttons(nbButtons).FontColor;
                app.HorseStruct(nbButtons).k = k;
            end


            if numel(app.HorseStruct)>0
                app.UITable.Data = app.HorseStruct(end).UITableData;

                [rowsize,~]=size(app.UITable.Data);
                app.UITable.Data{rowsize+1,1} = MyDate(app);
                app.UITable.Data{rowsize+1,2} = char("\color{black}Jeux rechargé correctement.");
            end
            drawnow
            scroll(app.UITable,'bottom')

            results = true;

        end

        function results = MyDate(app)
            results= char(string(datetime("now","TimeZone","Europe/Paris","Format","dd-MM HH:mm:ss")));

        end


        function results = SortHorse(app)
            ClassementDate = MyDate(app);
            [rowsize,~]=size(app.UITable.Data);

            kValues = zeros(numel(app.HorseStruct),1);

            for idx = 1:numel(app.HorseStruct)
                kValues(idx) = app.HorseStruct(idx).k;
            end

            [C,~,ic] = unique(kValues);
            a_counts = accumarray(ic,1);
            [value,index] = sort(C,"descend");
            names = {"1ere";"2eme";"3eme"};
            Position = 1;
            for idx=1:numel(value)
                indexToDisplay = [];
                for kidx = 1:numel(kValues)
                    if kValues(kidx) == value(idx)
                        indexToDisplay = [indexToDisplay, kidx];
                    end
                end

                if isscalar(indexToDisplay)

                    app.UITable.Data{rowsize+1,1} = ClassementDate;
                    app.UITable.Data{rowsize+1,2} = char("\color{black}Le Cheval" + app.HorseStruct(indexToDisplay).ColorTxt + " \color{black} est à la {\bf" + names{Position}+ "} position." + ...
                        " Il est à la case {\bf" + num2str(app.HorseStruct(indexToDisplay).k) + "}.");


                else
                    TxtToDispplay = "\color{black}Les Chevaux ";
                    for idxsub = 1:numel(indexToDisplay)
                        TxtToDispplay = strcat(TxtToDispplay, app.HorseStruct(indexToDisplay(idxsub)).ColorTxt);
                        if idxsub<a_counts(index(idx))
                            TxtToDispplay = strcat(TxtToDispplay, " et ");
                        end
                    end
                    TxtToDispplay = strcat(TxtToDispplay," \color{black} sont à la {\bf" + names{Position}+ "} position." + ...
                        " Ils sont à la case {\bf" + num2str(app.HorseStruct(indexToDisplay(idxsub)).k) + "}.");

                    app.UITable.Data{rowsize+1,1} = ClassementDate;
                    app.UITable.Data{rowsize+1,2} = char(TxtToDispplay);

                end
                Position = Position+a_counts(index(idx));
                [rowsize,~]=size(app.UITable.Data);
                if Position >= 4
                    break
                end

            end


            results = true;

        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function MyStartup(app)


            s1 = uistyle;
            s1.Interpreter = "Tex";
            addStyle(app.UITable,s1); % add style for the table

            if isdeployed
                [file,loc] = uigetfile('*.mat');
                f = fullfile(loc,file);
                try
                    A= load(f);
                catch
                    A= [];
                end
            end

            % if isequal(app.HuuuHuuuuAvanceButton,'Run')
            %     oldState = rng;
            %     cobj = onCleanup(@()rng(oldState));
            %     rng('shuffle');
            %
            %     numSpins = randi([3 10]); % Random number of spins
            %     offset = randi(n); % Random select
            %     numSteps = numSpins*n + offset;
            % else
            %     % Initial app state: just plot the wheel without spinning
            %     numSteps = 1;
            % end
            %% Horse
            [inpict, ~] = imread('Horse1.png');
            app.marker = inpict;
            alpha_tmp = inpict == 0;
            app.alpha = alpha_tmp(:,:,1) .* alpha_tmp(:,:,2) .* alpha_tmp(:,:,3);
            grayImage = rgb2gray(app.marker);
            app.binaryImage = grayImage < 200;
            % red = marker(:,:,1);
            % green = marker(:,:,2);
            % blue = marker(:,:,3);


            rb = uiradiobutton(app.ListedesChevauxButtonGroup);
            rb.Text = " Aucun selectionné";
            rb.FontSize = 22;
            rb.Position = [10 app.ListedesChevauxButtonGroup.InnerPosition(4)-10 358 22]; % [x y width height]

            %% UIAxes_1
            plot(app.UIAxes_1,1:200:2400,[zeros(12,1)],'-wo',MarkerSize=app.myMarkerSize,MarkerFaceColor='white',LineWidth=8);
            hold(app.UIAxes_1,'on');
            kk=0;
            for k =0:10
                if kk <10
                    offsetTxt = 20;
                else
                    offsetTxt = 35;
                end
                text(k*200-offsetTxt,0,num2str(kk),"FontSize",20,'Parent',app.UIAxes_1);

                % redScalar = randi(255);
                % greenScalar = randi(255);
                % blueScalar = randi(255);
                % red(binaryImage) = redScalar;
                % green(binaryImage) = greenScalar;
                % blue(binaryImage) = blueScalar;
                % app.marker_modif = cat(3,red,green,blue);

                % rb = uiradiobutton(app.SelectionduchevalButtonGroup);
                % rb.Text = " Cheval" + num2str(kk);
                % rb.FontSize = 22;
                % rb.Position = [10 app.SelectionduchevalButtonGroup.InnerPosition(4)-app.MyListPadding*kk-10 358 22]; % [x y width height]
                % rb.FontColor = [redScalar greenScalar blueScalar]/  256  ;


                % app.HorsePicture(kk) = imshow(app.marker_modif,'xdata',k2xData(k),'ydata',[-260 -60],'Parent',app.UIAxes_1);
                % app.HorsePicture(kk).AlphaData = alpha;
                kk=kk+1;
            end
            tmp = imshow(app.marker,'xdata',k2xData(app,0),'ydata',[-260 -60],'Parent',app.UIAxes_1);
            tmp.AlphaData = false;
            app.UIAxes_1.XLim = [-100 2200];
            app.UIAxes_1.YLim = [-350 50];

            %% UIAxes_2
            plot(app.UIAxes_2,(1-200):200:2400,[zeros(13,1)],'-wo',MarkerSize=app.myMarkerSize,MarkerFaceColor='white',LineWidth=8);
            hold(app.UIAxes_2,'on');

            for k =0:10
                if kk <10
                    offsetTxt = 20;
                else
                    offsetTxt = 35;
                end
                text(k*200-offsetTxt,0,num2str(kk),"FontSize",20,'Parent',app.UIAxes_2);
                kk=kk+1;


                % app.HorsePicture(kk) = imshow(marker_modif,'xdata',k2xData(k),'ydata',[-260 -60],'Parent',app.UIAxes_2);
                % app.HorsePicture(kk).AlphaData = alpha;
            end
            tmp = imshow(app.marker,'xdata',k2xData(app,0),'ydata',[-260 -60],'Parent',app.UIAxes_2);
            tmp.AlphaData = false;
            app.UIAxes_2.XLim = [-100 2200];
            app.UIAxes_2.YLim = [-350 50];

            %% UIAxes_3
            plot(app.UIAxes_3,(1-200):200:2400,[zeros(13,1)],'-wo',MarkerSize=app.myMarkerSize,MarkerFaceColor='white',LineWidth=8);
            hold(app.UIAxes_3,'on');
            app.UIAxes_3.XLim = [-100 2200];
            app.UIAxes_3.YLim = [-350 50];
            for k =0:10
                if kk <10
                    offsetTxt = 20;
                else
                    offsetTxt = 35;
                end
                text(k*200-offsetTxt,0,num2str(kk),"FontSize",20,'Parent',app.UIAxes_3);


                % app.HorsePicture(kk) = imshow(marker_modif,'xdata',k2xData(k),'ydata',[-260 -60],'Parent',app.UIAxes_3);
                % app.HorsePicture(kk).AlphaData = alpha;
                kk=kk+1;
            end
            tmp = imshow(app.marker,'xdata',k2xData(app,0),'ydata',[-260 -60],'Parent',app.UIAxes_3);
            tmp.AlphaData = false;
            %% UIAxes_4
            plot(app.UIAxes_4,(1-200):200:2400,[zeros(13,1)],'-wo',MarkerSize=app.myMarkerSize,MarkerFaceColor='white',LineWidth=8);
            hold(app.UIAxes_4,'on');

            for k =0:10
                if kk <10
                    offsetTxt = 20;
                else
                    offsetTxt = 35;
                end
                text(k*200-offsetTxt,0,num2str(kk),"FontSize",20,'Parent',app.UIAxes_4);
                kk=kk+1;


                % app.HorsePicture(kk) = imshow(marker_modif,'xdata',k2xData(k),'ydata',[-260 -60],'Parent',app.UIAxes_4);
                % app.HorsePicture(kk).AlphaData = alpha;

            end
            tmp = imshow(app.marker,'xdata',k2xData(app,0),'ydata',[-260 -60],'Parent',app.UIAxes_4);
            tmp.AlphaData = false;
            app.UIAxes_4.XLim = [-100 2200];
            app.UIAxes_4.YLim = [-350 50];


            %% Wheel
            names = strsplit(app.Step,app.delim);
            n = numel(names);
            app.h = pie(app.Wheel,ones(n,1),names);

            % Keep track of spinner from last run
            if numel(app.SpinnerLocation) ~= n
                % Reset to first if the number of names changes
                m = [true; false(n-1,1)];
            else
                m = app.SpinnerLocation;
            end
            set(app.h(2:2:end),'FontSize',12);
            set(app.h(2:2:end),'FontWeight','bold');
            set(app.h(1:2:end),'FaceColor','flat');
            k=1;
            m = circshift(m,double(k > 1));
            ind = 2*find(m)-1;
            set(app.h(ind),'FaceColor',app.c);
            drawnow

            if not(isdeployed)
                if exist('SauvegardeApp.mat','file')
                    A= load("SauvegardeApp.mat");
                    app.HorseStruct = A.savedHorseStruct;
                    reloadGame(app);
                end
            else
                if not(isempty(A))
                    app.HorseStruct = A.savedHorseStruct;
                    reloadGame(app);
                end
            end
            drawnow
            HorseGameSelectionChanged(app, "");

        end

        % Button pushed function: HuuuHuuuuAvanceButton
        function HorseRun(app, event)
            app.HuuuHuuuuAvanceButton.Enable = "off";
            m =  app.SpinnerLocation;
            ind = 2*find(m)-1;
            set(app.h(ind+1),'FontSize',12,'BackgroundColor',"none",'EdgeColor',"none");
            names = strsplit(app.Step,app.delim);
            n = numel(names);
            oldState = rng;
            cobj = onCleanup(@()rng(oldState));
            rng('shuffle');
            numSpins = randi([1 3]); % Random number of spins
            offset = randi(n); % Random select
            numSteps = numSpins*n + offset;
            % Keep track of spinner from last run
            if numel(app.SpinnerLocation) ~= n
                % Reset to first if the number of names changes
                m = [true; false(n-1,1)];
            else
                m = app.SpinnerLocation;
            end

            for k = 1:numSteps
                set(app.h(1:2:end),'FaceColor','flat');

                m = circshift(m,double(k > 1));
                ind = 2*find(m)-1;
                set(app.h(ind),'FaceColor',app.c);
                drawnow
                %                 pause(0.01*log(k))
                %                 pause(0.001*k)
                pause(0.05);
            end
            % Save spinner location
            app.SpinnerLocation = m;
            % Highlight the result
            set(app.h(ind+1),'FontSize',16,'BackgroundColor',app.c,'EdgeColor',app.c);
            app.HuuuHuuuuAvanceButton.Enable = "on";

            RunHorseRun(app,str2double(names(m)));
            index = HorseRunSelected(app);
            [rowsize,~]=size(app.UITable.Data);
            app.UITable.Data{rowsize+1,1} = MyDate(app);
            app.UITable.Data{rowsize+1,2} = char("\color{black}Cheval" + app.HorseStruct(index).ColorTxt + " \color{black} a avancé de {\bf" + string(names(m))+ "} case(s). Il est maintenant à la case {\bf" + num2str(app.HorseStruct(index).k) + "}.");
            SortHorse(app);
            drawnow
            scroll(app.UITable,'bottom')
            scroll(app.UITable,'bottom')


        end

        % Button pushed function: CreerButton
        function CreerCheval(app, event)

            resultExist = CheckHorseExist(app,app.NomduChevalEditField.Value,0);
            if ~resultExist
                resultColor = CheckHorseColorExist(app,0);
                if ~resultColor
                    nbButtons = numel(app.ListedesChevauxButtonGroup.Buttons);
                    rb = uiradiobutton(app.ListedesChevauxButtonGroup);
                    rb.Text = " " + app.NomduChevalEditField.Value;
                    rb.FontSize = 22;
                    rb.Position = [10 app.ListedesChevauxButtonGroup.InnerPosition(4)-10-app.MyListPadding*(nbButtons+1) 358 22]; % [x y width height]
                    rb.FontColor = app.CouleurduChevalColorPicker.Value;    % [redScalar greenScalar blueScalar]/  256  ;

                    sb = uiradiobutton(app.SelectionduchevalButtonGroup);
                    sb.Text = " " + app.NomduChevalEditField.Value;
                    sb.FontSize = 22;
                    sb.Position = [10 app.SelectionduchevalButtonGroup.InnerPosition(4)-10-app.MyListPadding*nbButtons 358 22]; % [x y width height]
                    sb.FontColor = app.CouleurduChevalColorPicker.Value;    % [redScalar greenScalar blueScalar]/  256  ;

                    k=0;
                    red = app.marker(:,:,1);
                    green = app.marker(:,:,2);
                    blue = app.marker(:,:,3);
                    red(app.binaryImage) = app.CouleurduChevalColorPicker.Value(1)*255;
                    green(app.binaryImage) = app.CouleurduChevalColorPicker.Value(2)*255;
                    blue(app.binaryImage) = app.CouleurduChevalColorPicker.Value(3)*255;
                    marker_modif = cat(3,red,green,blue);
                    app.HorsePicture(nbButtons) = imshow(marker_modif,'xdata',k2xData(app,k),'ydata',[-260 -60],'Parent',app.UIAxes_1);
                    app.HorsePicture(nbButtons).AlphaData = app.alpha;
                    xData2k(app,app.HorsePicture(nbButtons).XData,app.HorsePicture(nbButtons).Parent);


                    app.HorseStruct(nbButtons).Name = app.SelectionduchevalButtonGroup.Buttons(nbButtons).Text;
                    app.HorseStruct(nbButtons).Color = app.SelectionduchevalButtonGroup.Buttons(nbButtons).FontColor;
                    app.HorseStruct(nbButtons).k = k;
                    app.HorseStruct(nbButtons).ColorTxt = "{\bf" + sprintf('\\color[rgb]{%f, %f, %f}%s', app.SelectionduchevalButtonGroup.Buttons(nbButtons).FontColor) + string(app.HorseStruct(nbButtons).Name) + "}";
                end
                [rowsize,~]=size(app.UITable.Data);
                app.UITable.Data{rowsize+1,1} = MyDate(app);
                app.UITable.Data{rowsize+1,2} = char("\color{black}Cheval" + app.HorseStruct(nbButtons).ColorTxt + " \color{black}créé.");
                scroll(app.UITable,'bottom');
                HorseGameSelectionChanged(app, "");
            end

        end

        % Button pushed function: SupprimerButton
        function DeleteHorse(app, event)
            idx = HorseCreationSelected(app);

            if not(idx == 1)
                for idxsub = idx:numel(app.ListedesChevauxButtonGroup.Buttons)
                    if idxsub == idx
                        app.ListedesChevauxButtonGroup.Buttons(idxsub).delete;
                        app.SelectionduchevalButtonGroup.Buttons(idxsub-1).delete;
                        app.HorsePicture(idxsub-1).AlphaData = false;
                        delete(app.HorsePicture(idxsub-1));
                        app.HorseStruct(idxsub-1) = [];
                    else
                        app.ListedesChevauxButtonGroup.Buttons(idxsub-1).Position = [10 app.ListedesChevauxButtonGroup.InnerPosition(4)-10-app.MyListPadding*(idxsub-1) 358 22]; % [x y width height]
                        app.SelectionduchevalButtonGroup.Buttons(idxsub-2).Position =[10 app.SelectionduchevalButtonGroup.InnerPosition(4)-10-app.MyListPadding*(idxsub-2) 358 22]; % [x y width height]
                    end
                end
            end


        end

        % Button pushed function: ModifierButton
        function ModifyHorse(app, event)
            idx = HorseCreationSelected(app);

            resultExist = CheckHorseExist(app,app.NomduChevalEditField.Value,idx);
            if ~resultExist
                resultColor = CheckHorseColorExist(app,idx);
                if ~resultColor
                    if not(idx == 1)
                        app.ListedesChevauxButtonGroup.Buttons(idx).Text = " " + app.NomduChevalEditField.Value;
                        app.ListedesChevauxButtonGroup.Buttons(idx).FontColor = app.CouleurduChevalColorPicker.Value;
                        app.SelectionduchevalButtonGroup.Buttons(idx-1).Text = " " + app.NomduChevalEditField.Value;
                        app.SelectionduchevalButtonGroup.Buttons(idx-1).FontColor = app.CouleurduChevalColorPicker.Value;
                        k =  app.HorseStruct(idx-1).k;
                        Parent = k2Parent(app,k);
                        delete(app.HorsePicture(idx-1));
                        red = app.marker(:,:,1);
                        green = app.marker(:,:,2);
                        blue = app.marker(:,:,3);
                        red(app.binaryImage) = app.CouleurduChevalColorPicker.Value(1)*255;
                        green(app.binaryImage) = app.CouleurduChevalColorPicker.Value(2)*255;
                        blue(app.binaryImage) = app.CouleurduChevalColorPicker.Value(3)*255;
                        marker_modif = cat(3,red,green,blue);
                        app.HorsePicture(idx-1) = imshow(marker_modif,'xdata',k2xData(app,k),'ydata',[-260 -60],'Parent',Parent);
                        app.HorsePicture(idx-1).AlphaData = app.alpha;
                        app.HorseStruct(idx-1).Name = app.SelectionduchevalButtonGroup.Buttons(idx-1).Text;
                        app.HorseStruct(idx-1).Color = app.SelectionduchevalButtonGroup.Buttons(idx-1).FontColor;
                        app.HorseStruct(idx-1).k = k;

                    end
                end
            end
        end

        % Close request function: UIFigure
        function Close(app, event)
            if numel(app.HorseStruct)>0
                app.HorseStruct(end).UITableData = app.UITable.Data;
            end
            savedHorseStruct = app.HorseStruct;
            if isdeployed
                [f, p] = uiputfile('*.mat');
                save(fullfile(p,f),"savedHorseStruct")
            else
                save("SauvegardeApp.mat","savedHorseStruct")
            end
            delete(app)

        end

        % Selection changed function: ListedesChevauxButtonGroup
        function HorseConfigSelectionChanged(app, event)
            selectedButton = app.ListedesChevauxButtonGroup.SelectedObject;
            app.NomduChevalEditField.Value = selectedButton.Text(2:end);
            app.CouleurduChevalColorPicker.Value =   selectedButton.FontColor;

        end

        % Selection changed function: SelectionduchevalButtonGroup
        function HorseGameSelectionChanged(app, event)
            try
            index = HorseRunSelected(app);
            TxtToDisplay = "\color{black}Huuu, Huuuu." + app.HorseStruct(index).ColorTxt +  "\color{black} Avance !";

            app.HuuuHuuuuAvanceButton.Text = TxtToDisplay;
            catch
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @Close, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.JeuxTab = uitab(app.TabGroup);
            app.JeuxTab.Title = 'Jeux';
            app.GridLayout = uigridlayout(app.JeuxTab);
            app.Wheel = uiaxes(app.GridLayout);
            xlabel(app.Wheel, 'X')
            ylabel(app.Wheel, 'Y')
            zlabel(app.Wheel, 'Z')
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'Date'; 'Message'};
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            app.RecapLogLabel = uilabel(app.GridLayout2);
            app.RecapLogLabel.Text = 'Recap Log';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.GridLayout7 = uigridlayout(app.GridLayout3);
            app.HuuuHuuuuAvanceButton = uibutton(app.GridLayout7, 'push');
            app.HuuuHuuuuAvanceButton.ButtonPushedFcn = createCallbackFcn(app, @HorseRun, true);
            app.HuuuHuuuuAvanceButton.Interpreter = 'tex';
            app.HuuuHuuuuAvanceButton.Text = 'Huuu, Huuuu.  Avance !';
            app.SelectionduchevalButtonGroup = uibuttongroup(app.GridLayout);
            app.SelectionduchevalButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @HorseGameSelectionChanged, true);
            app.SelectionduchevalButtonGroup.Title = 'Selection du cheval';
            app.GridLayout6 = uigridlayout(app.GridLayout);
            app.UIAxes_1 = uiaxes(app.GridLayout6);
            app.UIAxes_1.XTick = [];
            app.UIAxes_1.YTick = [];
            app.UIAxes_2 = uiaxes(app.GridLayout6);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_3 = uiaxes(app.GridLayout6);
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_4 = uiaxes(app.GridLayout6);
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.ConfigurationTab = uitab(app.TabGroup);
            app.ConfigurationTab.Title = 'Configuration';
            app.GridLayout5 = uigridlayout(app.ConfigurationTab);
            app.GridLayout4 = uigridlayout(app.GridLayout5);
            app.CouleurduChevalColorPickerLabel = uilabel(app.GridLayout4);
            app.CouleurduChevalColorPickerLabel.Text = 'Couleur du Cheval';
            app.CouleurduChevalColorPicker = uicolorpicker(app.GridLayout4);
            app.NomduChevalEditFieldLabel = uilabel(app.GridLayout4);
            app.NomduChevalEditFieldLabel.Text = 'Nom du Cheval';
            app.NomduChevalEditField = uieditfield(app.GridLayout4, 'text');
            app.NomduChevalEditField.Value = 'Cheval';
            app.CreerButton = uibutton(app.GridLayout4, 'push');
            app.CreerButton.ButtonPushedFcn = createCallbackFcn(app, @CreerCheval, true);
            app.CreerButton.Text = 'Créer';
            app.ModifierButton = uibutton(app.GridLayout4, 'push');
            app.ModifierButton.ButtonPushedFcn = createCallbackFcn(app, @ModifyHorse, true);
            app.ModifierButton.Text = 'Modifier';
            app.SupprimerButton = uibutton(app.GridLayout4, 'push');
            app.SupprimerButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteHorse, true);
            app.SupprimerButton.Text = 'Supprimer';
            app.ListedesChevauxButtonGroup = uibuttongroup(app.GridLayout5);
            app.ListedesChevauxButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @HorseConfigSelectionChanged, true);
            app.ListedesChevauxButtonGroup.Title = 'Liste des Chevaux';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @MyStartup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end